classdef Final_Project_Feet_Classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        fileName                        matlab.ui.control.Label
        previousimageButton             matlab.ui.control.Button
        nextimageButton                 matlab.ui.control.Button
        RGBDimensionsButtonGroup        matlab.ui.container.ButtonGroup
        RedButton                       matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        mainAxes                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        colorationCorrection            matlab.ui.container.Panel
        ResetColorsButton               matlab.ui.control.Button
        CorrectColorsButton             matlab.ui.control.Button
        redSlider                       matlab.ui.control.Slider
        EnhanceredSliderLabel           matlab.ui.control.Label
        blueSlider                      matlab.ui.control.Slider
        EnhanceblueSliderLabel          matlab.ui.control.Label
        colorDescription                matlab.ui.control.Label
        FoottypeButtonGroup             matlab.ui.container.ButtonGroup
        foottypeDescription             matlab.ui.control.Label
        WhenloadingtheimagetheappLabel  matlab.ui.control.Label
        RightfootButton                 matlab.ui.control.RadioButton
        LeftfootButton                  matlab.ui.control.RadioButton
        QuickcheckimagePanel            matlab.ui.container.Panel
        rotateDescription               matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        RotateButton                    matlab.ui.control.Button
        LoadimagePanel                  matlab.ui.container.Panel
        rotateimageButton               matlab.ui.control.StateButton
        ExpertmodeButton                matlab.ui.control.StateButton
        UselastpathCheckBox             matlab.ui.control.CheckBox
        LoadButton                      matlab.ui.control.Button
        DataProcessingTab               matlab.ui.container.Tab
        ContactDetectionPanel           matlab.ui.container.Panel
        CutoffvalueSpinner              matlab.ui.control.Spinner
        CutoffvalueSpinnerLabel         matlab.ui.control.Label
        Iterations_EditField_2          matlab.ui.control.NumericEditField
        alliterationsLabel_2            matlab.ui.control.Label
        ShowColorGraphButton            matlab.ui.control.Button
        RGBDimensionsButtonGroup_2      matlab.ui.container.ButtonGroup
        RedButton_2                     matlab.ui.control.RadioButton
        GreenButton_2                   matlab.ui.control.RadioButton
        BlueButton_2                    matlab.ui.control.RadioButton
        CIBandwidthEditField            matlab.ui.control.NumericEditField
        CIBandwidthEditFieldLabel       matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        BackgroundDetectionPanel        matlab.ui.container.Panel
        Detection_window_EditField      matlab.ui.control.NumericEditField
        DetectionWindowEditFieldLabel   matlab.ui.control.Label
        Iterations_EditField            matlab.ui.control.NumericEditField
        alliterationsLabel              matlab.ui.control.Label
        Neighbour_threshold_Slider      matlab.ui.control.Slider
        NeighbourthresholdLabel         matlab.ui.control.Label
        Refine_detection_Button         matlab.ui.control.Button
        Detect_foot_pixel_Button        matlab.ui.control.Button
        Threshold_Slider                matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        LandmarkIdentificationTab       matlab.ui.container.Tab
        Blobsize1nimagesizeEditField    matlab.ui.control.NumericEditField
        Blobsize1nimagesizeLabel        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FootprintindexButton            matlab.ui.control.StateButton
        ArchlengthindexButton           matlab.ui.control.StateButton
        FootprintindexEditField         matlab.ui.control.NumericEditField
        ArchlengthindexEditField        matlab.ui.control.NumericEditField
        findheelButton                  matlab.ui.control.Button
        determinepointsButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %% to do
    % optional(create image with colors)
    % callback bei switch RGB dimensions/process
    % Parameter: ABC and truncated
    % adding lines manually
    % rotieren

    properties (Access = private)
        image_dispalyed
        file
        app_settings
        working_directory
        version
        image_display_counter
        heel_point_idx
        colorsChanged
    end

    methods (Access = private)
        %% differentiates between image and background
        function image_cut_out = background_removal(~, image, color, threshold)
            %color white = 1
            %color black = 0
            size_image = size(image);
            for i_row = 1 : size_image(1) % rows
                for i_col = 1 : size_image(2) % columns
                    if color
                        if image(i_row,i_col,1)>=(threshold/100) && image(i_row,i_col,2)>=(threshold/100) && image(i_row,i_col,3)>=(threshold/100)
                            image_cut_out(i_row,i_col) = 0; %#ok<*AGROW>
                        else
                            image_cut_out(i_row,i_col) = 1;
                        end
                    else
                        if image(i_row,i_col,1)<= 1-(threshold/100) && image(i_row,i_col,2)<= 1-(threshold/100) && image(i_row,i_col,3)<= 1-(threshold/100)
                            image_cut_out(i_row,i_col) = 0;
                            image_cut_out(i_row,i_col) = 0;
                        else
                            image_cut_out(i_row,i_col) = 1;
                        end
                    end
                end
            end
        end

        %% refines image/background seperation by checking neighbours
        function output_logical = Neighborhood_refinement(app, image_logical)
            Size_log = size(image_logical);
            output_logical = nan(Size_log);
            Raster = ones(app.Detection_window_EditField.Value); % Raster of detection
            Neighborhood = conv2(image_logical, Raster, 'same'); % determine the sum of the neighbouring pixels
            cut_off = (app.Neighbour_threshold_Slider.Value .* numel(Raster) ) ./ 100;
            for row = 1:size(image_logical, 1)
                for col = 1:size(image_logical, 2)
                    for dim = 1:size(image_logical, 3)
                        if Neighborhood(row, col, dim) >= cut_off %#ok<*BDSCI>
                            output_logical(row, col, dim) = 1;
                        elseif Neighborhood(row, col, dim) < cut_off
                            output_logical(row, col, dim) = 0;
                        end
                    end
                end
            end
            
        end

        %% function changes mouse cursor
        function hourglass_cursor(app,value)
            if value
                set(app.UIFigure,'Pointer', 'watch');
                drawnow;
            else
                set(app.UIFigure,'Pointer', 'arrow');
                drawnow;
            end
        end

        %% safes images of all processing steps and determines current image for axes
        function version_control(app,image,title)
            if isempty(app.version{1,1})
                index = 1;
            else
                index = (length(app.version(:,1))) +1;
            end
            app.version{index,1} = image;
            app.version{index,2} = size(image,3);
            app.version{index,3} = title;
            app.image_dispalyed = image;
        end

        %% shows current image in axes
        % move is 1 if user wants to show next image and -1 if previous
        function show_image(app,move)
            if nargin == 1
                index = size(app.version,1);
                app.image_display_counter = size(app.version,1);
                %set temp image to selected dimension
            else
                if (app.image_display_counter + move) <= size(app.version,1) && (app.image_display_counter + move) >= 1
                    app.image_display_counter = app.image_display_counter + move;
                    index = app.image_display_counter;
                else
                    index = app.image_display_counter;
                end
            end
            current_image = app.version{index, 1};
            image_dims = app.version{index, 2};
            current_title = app.version{index, 3};
            temp_image = current_image;
            if image_dims > 1
                if app.RedButton.Value
                    temp_image(:, :, 1) = current_image(:,:,1);
                    temp_image(:, :, 2:3) = 0;
                elseif app.BlueButton.Value
                    temp_image(:, :, 3) = current_image(:,:,3);
                    temp_image(:, :, 1:2) = 0;
                elseif app.GreenButton.Value
                    temp_image(:, :, 2) = current_image(:,:,2);
                    temp_image(:, :, 1:2:3) = 0;
                end
            end


            % plot image
            imshow(temp_image,"Parent",app.mainAxes);
            title(app.mainAxes, current_title);
            axis(app.mainAxes,'off');
        end


        %% classify pixels based on trainings group
        function [trained_image, trainedLogical2, coloredMask] = classify_image(app, picture)
            
            % create trainings group
            % group 1 = no contact, group 2 = contact
            if app.RedButton_2.Value
                color_layer = picture(:, :, 1);
            elseif app.GreenButton_2.Value
                color_layer = picture(:, :, 2);
            elseif app.BlueButton_2.Value
                color_layer  = picture(:, :, 3);
            end
            red_layer = picture(:, :, 1);
            green_layer = picture(:, :, 2);
            blue_layer = picture(:, :, 3);
            trainings_counter = 1;

            % before training -> display undecided areas
            undecided = zeros(size(picture));
            for rows = 1 : size(green_layer, 1)
                for cols = 1 : size(green_layer, 2)
                    if color_layer(rows, cols) < app.file.CImax1(2)
                        undecided(rows, cols, 1) = 1;
                    elseif color_layer(rows, cols) > app.file.CImax2(1)
                        undecided(rows, cols, 2) = 1;
                    else
                        undecided(rows, cols, 3) = 1;
                    end
                end
            end

            for i = 1:(size(color_layer, 1) * size(color_layer, 2))
                if color_layer(i) < app.file.CImax1(2) % is not contact
                    group(trainings_counter) = 0;
                    training(trainings_counter, 1) = red_layer(i);
                    training(trainings_counter, 2) = green_layer(i);
                    training(trainings_counter ,3) = blue_layer(i);
                elseif color_layer(i) > app.file.CImax2(1) % is contact
                    group(trainings_counter) = 1; 
                    training(trainings_counter, 1) = red_layer(i);
                    training(trainings_counter, 2) = green_layer(i);
                    training(trainings_counter, 3) = blue_layer(i);
                end
                trainings_counter = trainings_counter +1;
                sample(i, 1) = red_layer(i);
                sample(i, 2) = green_layer(i);
                sample(i, 3) = blue_layer(i);
            end
            sample = cast(sample, 'double');
            training = cast(training, 'double');
            class = classify(sample, training, group');

            % change back all vectors to matrices
            end_indices = [0, size(app.file.image_DW, 1):(size(app.file.image_DW, 1)):length(class)];
            for column_counter = 1:(length(end_indices) -1)
                trainedLogical1(:, column_counter) = class((end_indices(column_counter)+1):end_indices(column_counter +1));
            end

            % make trained_logical a true logical
            logical = cast(trainedLogical1, 'logical');
            % get biggest parts
            biggestAreas = bwareafilt(logical, 2, 'largest');
            biggestAreas = cast(biggestAreas, 'double');
            % fill holes
            filled = imfill(biggestAreas, 8, 'holes');
            assignin("base", "beforeRefinement", filled)
                        
            % smooth edges
            for i = 1: app.Iterations_EditField_2.Value
                grid = ones(3); % Raster of detection
                sumNeighbors = conv2(filled, grid, 'same'); % determine the sum of the neighbouring pixels
                cut_off = app.CutoffvalueSpinner.Value * sum(grid, "all");
                refined = nan(size(filled));
    
                for row = 1 : size(filled, 1)
                    for col = 1 : size(filled, 2)
                        if sumNeighbors(row, col) >= cut_off
                            refined(row, col) = 1;
                        elseif sumNeighbors(row, col) < cut_off
                            refined(row, col) = 0;
                        end
                    end
                end
                filled = refined;
            end
            assignin("base", "refined", refined)
            % colored floor contact area
            coloredMask = zeros(size(trainedLogical1));
            for row = 1 : size(filled, 1)
                for col = 1 : size(filled, 2)
                    if app.file.logical(row, col) && refined(row, col) == 1
                        coloredMask(row, col) = 1;
                    end
                end
            end
            
            % revert
            trainedLogical2 = (refined .* -1) +1;
            
            %
            assignin("base", "undecided", undecided)
            assignin("base", "trainedLogical", trainedLogical1)
            resulting_foot_uint8 = cast(trainedLogical2,'uint8');
            trained_image = resulting_foot_uint8 .* picture;
            assignin("base", "pic", picture)
            
        end

        %% function that removes border from rotated images
        function [picture, rows_to_delete, cols_to_delete] = remove_borders(~, picture,background)
            rows_to_delete = [];
            cols_to_delete = [];
            for row = 1:size(picture,1)
                if sum(picture(row,:,1)) == (0 + background) && sum(picture(row,:,2)) == (0 + background) && sum(picture(row,:,3)) == (0 + background)
                    rows_to_delete = [rows_to_delete,row];
                end
            end
            picture(rows_to_delete,:,:) = [];
            for col = 1:size(picture,2)
                if sum(picture(:,col,1)) == (0 + background) && sum(picture(:,col,2)) == (0 + background) && sum(picture(:,col,3)) == (0 + background)
                    cols_to_delete = [cols_to_delete,col];
                end
            end
            picture(:,cols_to_delete,:) = [];
        end

        %% function that determines horizontal borderlines of image
        function [x,y,x_left,y_left, x_right, y_right] = horizontal_borderlines(~, binary_image)
            size_image = size(binary_image,1);
            y_right = nan((size_image), 1);
            x_right = nan((size_image), 1);
            y_left = nan((size_image), 1);
            x_left = nan((size_image), 1);
            for row = 1:size_image
                left_point_col = find(binary_image(row, :), 1, 'first');
                right_point_col = find(binary_image(row, :), 1, 'last');
                if ~isempty(left_point_col)
                    y_left(row) = size_image + 1 - row;
                    x_left(row) = left_point_col;
                end
                if ~isempty(right_point_col)
                    y_right(end - row) = size_image + 1 - row;
                    x_right(end - row) = right_point_col;
                end
            end
            x_both = [x_left, x_right];
            y_both = [y_left, y_right];
            x = x_both(~isnan(x_both));
            y = y_both(~isnan(y_both));
            x_right = x_right(~isnan(x_right));
            y_right = y_right(~isnan(y_right));
            x_left = x_left(~isnan(x_left));
            y_left = y_left(~isnan(y_left));
        end

        %% function that determines vertical borderlines of image
        function [x,y,x_top,y_top, x_bot, y_bot] = vertical_borderlines(~, binary_image)
            size_image = size(binary_image, 2);
            y_bot = nan((size_image), 2);
            x_bot = nan((size_image), 2);
            y_top = nan((size_image), 2);
            x_top = nan((size_image), 2);
            for col = 1:size_image
                top_point_row = find(binary_image(:,col), 1, 'first');
                bottom_point_row = find(binary_image(:,col), 1, 'last');
                if ~isempty(top_point_row)
                    y_top(col) = size_image + 1 - col;
                    x_top(col) = top_point_row;
                end
                if ~isempty(bottom_point_row)
                    y_bot(end - col) = size_image + 1 - col;
                    x_bot(end - col) = bottom_point_row;
                end
            end
            x_both = [x_top, x_bot];
            y_both = [y_top, y_bot];
            x = x_both(~isnan(x_both));
            y = y_both(~isnan(y_both));
            x_bot = x_bot(~isnan(x_bot));
            y_bot = y_bot(~isnan(y_bot));
            x_top = x_top(~isnan(x_top));
            y_top = y_top(~isnan(y_top));
        end

        %% function that returns row and col of marked pixels (averaged pos of cluster)
        function [heel, toe] = find_marked_pixel(~, image, logical)
            heel_pixel = [];
            toe_pixel = [];
            for col = 1:size(image,2)
                for row = 1:size(image,1)
                    if image(row,col,1) == 1 && image(row,col,2) == 0 && image(row,col,3) == 0
                        if row < (size(image,1) /2)
                            toe_pixel = [toe_pixel, col];
                        else
                            heel_pixel = [heel_pixel, col];
                        end
                    end
                end
            end
            % determine column based on last foot pixel in found row
            heel = nan(1,2);
            heel(2) = floor(mean(heel_pixel));
            heel(1) = find(logical(:,heel_pixel(2)) == 1,1,'last');
            toe = nan(1,2);
            toe(2) = floor(mean(toe_pixel));
            toe(1) = find(logical(:,toe(2)) == 1,1,'first');
        end
        
        function colorGraph(app)
            cla(app.UIAxes2, "reset");
            if app.RedButton_2.Value
                RGB_intensity  = app.file.image_DW(:, :, 1);
                color = [1, 0, 0];
            elseif app.GreenButton_2.Value
                RGB_intensity  = app.file.image_DW(:, :, 2);
                color = [0.4660 0.6740 0.1880];
            elseif app.BlueButton_2.Value
                RGB_intensity  = app.file.image_DW(:, :, 3);
                color = [0 0.4470 0.7410];
            end

            all_intensities = 1 : 255; % vector for the intensity values from 0 to 255
            n_intensity  = zeros(size(all_intensities));
            for ctr = all_intensities
                % search for each intensity value & count its occurance
                n_intensity(ctr) = length(find(RGB_intensity == ctr));
            end

            % sum intensity vector & find idx of median
            median = sum(n_intensity, "all")/2;
            sum_up_intensity = 0; median_idx = 0;
            while sum_up_intensity < median
                median_idx = median_idx + 1;
                sum_up_intensity = sum_up_intensity + n_intensity(median_idx);
            end

            % maxima
            [peaks_left, idx_peaks_left] = findpeaks(n_intensity(1:median_idx));
            [peaks_right, idx_peaks_right] = findpeaks(n_intensity(median_idx:end));
            [~, idx1] = max(peaks_left);
            [~, idx2] = max(peaks_right);
            max1 = peaks_left(idx1);
            max2 = peaks_right(idx2);
            max1_idx = idx_peaks_left(idx1);
            max2_idx = idx_peaks_right(idx2) +median_idx -1;
            % minimum
            [minimum, min_idx] = min(n_intensity(max1_idx : max2_idx));
            min_idx = min_idx + max1_idx -1;
            % confidence bands
            width = app.CIBandwidthEditField.Value;
            app.file.CImax1(1, 1) = max1_idx - width*numel(max1_idx : min_idx); % 1 lower
            app.file.CImax1(1, 2) = max1_idx + width*numel(max1_idx : min_idx); % 1 upper
            app.file.CImax2(1, 1) = max2_idx - width*numel(min_idx : max2_idx); % 2 lower
            app.file.CImax2(1, 2) = max2_idx + width*numel(min_idx : max2_idx); % 2 upper

            % plot
            plot(app.UIAxes2, n_intensity,'k');
            hold(app.UIAxes2, "on");
            xline(app.UIAxes2, median_idx,'r');
            plot(app.UIAxes2, max1_idx, max1, 'xk');
            plot(app.UIAxes2, max2_idx, max2, 'xk');
            plot(app.UIAxes2, min_idx, minimum, 'ok');
            legend(app.UIAxes2, 'Intensity', 'Modus', 'Maximum1', 'Maximum2', 'Minimum');
            % determine and plot bands
            area(app.UIAxes2, [app.file.CImax1(1), app.file.CImax1(2)], [max1, max1], "FaceAlpha", 0.3, "FaceColor", color)
            area(app.UIAxes2, [app.file.CImax2(1), app.file.CImax2(2)], [max2, max2], "FaceAlpha", 0.3, "FaceColor", color)
            legend(app.UIAxes2, 'Intensity', 'Modus', 'Maximum1', 'Maximum2', 'Minimum', 'CI', 'CI');            
        end

        %% function that resets all state buttons of parameters

        function clear_state_buttons(app)
        app.FootprintindexButton.Value = 0;
        app.ArchlengthindexButton.Value = 0;
        end
        
        %% color correction for yellow photos
        function colorCorrection(app)
            red = app.redSlider.Value;
            blue = app.blueSlider.Value;
            app.file.coloration(:, :, 1) = app.file.coloration(:, :, 1) * red;
            app.file.coloration(:, :, 3) = app.file.coloration(:, :, 3) * blue;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialise variables
            app.file = struct();
            app.AllButton.Value = 1;
            app.GreenButton_2.Value = 1;
            app.colorsChanged = 0;

            % go to directory of app
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            app.working_directory = filepath;
            cd(filepath);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            cd(app.working_directory);
            cla(app.mainAxes,"reset");
            cla(app.UIAxes2,"reset");
            close all;
            % reset image versions and counter for displaying images
            app.version = cell(1,3);
            app.image_display_counter = 0;

            if app.UselastpathCheckBox.Value == false
                seleceted_path = uigetdir;
                save("App_Settings","seleceted_path");
            else
                if exist(fullfile(app.working_directory,"App_Settings.mat"),"file")
                    app.app_settings = load("App_Settings.mat","seleceted_path");
                    seleceted_path = cell2mat(struct2cell(app.app_settings));
                else
                    seleceted_path = uigetdir;
                    save("App_Settings","seleceted_path");
                end
            end
            cd(seleceted_path);
            filename = uigetfile('.jpg');
            % get app back in foreground
            drawnow;
            figure(app.UIFigure)
            % safe image in struct and version control
            app.file.raw = imread(filename,'jpg');
            app.fileName.Text = filename;
            % transform into double
            app.file.double = cast(app.file.raw,'double') / 255;
            % check wether backgroundcolor is white or black
            boarderpoint = app.file.double(1,1) + app.file.double(1,end ) + app.file.double(end,1) + app.file.double(end,end);
            if  boarderpoint >=2
                app.file.backgroundcolor = 1; % white background
            else
                app.file.backgroundcolor = 0; % black background
            end
            app.file.double = remove_borders(app,app.file.double,app.file.backgroundcolor);
            version_control(app,app.file.double,'Raw');
            show_image(app)

            %% determine if left or right foot
            count_right_side = 0;
            count_left_side = 0;
            first_pixel = [];
            % determine if file name contains letter r or l
            if contains(filename, 'r')|| contains(filename, 'R')
                count_right_side = count_right_side +1;
            end
            if contains(filename, 'l') || contains(filename, 'L')
                count_left_side = count_left_side +1;
            end
            % determine edges of foot
            edges_image = edge(rgb2gray(app.file.double),"canny");
            % search first pixel of foot edge
            for row = 1:size(edges_image,1)
                for columns = 1:size(edges_image,2)
                    if edges_image(row, columns)
                        first_pixel = columns;
                        break
                    end
                end
                if ~isempty(first_pixel)
                    break
                end
            end

            % if first
            if first_pixel < (size(edges_image,2) /2)
                count_right_side = count_right_side +1;
            else
                count_left_side = count_left_side +1;
            end

            if count_right_side > count_left_side
                app.RightfootButton.Value = 1;
            elseif count_right_side < count_left_side
                app.LeftfootButton.Value = 1;
            else
                uialert(app.UIFigure,'Side dectection not clear. Check selection and correct manually, if necessary.','Side detection not conclusive');
            end

            %% rotate image if state button in enabled
            if app.rotateimageButton.Value
                % select only bottom part of image = 1/n_parts
                n_parts = 4;
                hourglass_cursor(app,1);
                if app.RightfootButton.Value
                    rotation_values = 38:-4:-10;
                else
                    rotation_values = -38:4:10;
                end
                sum_values = nan(1,length(rotation_values));
                for rotate_counter = 1:length(rotation_values)
                    temp_image{rotate_counter} = imrotate(app.file.double,rotation_values(rotate_counter));
                    [temp_image{rotate_counter},~,~] = remove_borders(app,temp_image{rotate_counter},app.file.backgroundcolor);
                    cut_out_logical = app.background_removal(temp_image{rotate_counter}, app.file.backgroundcolor, app.Threshold_Slider.Value);
                    temp_image{rotate_counter} = cut_out_logical .* temp_image{rotate_counter};
                    % if image has white background, imrotate creates black background for new fields --> both background removals
                    % necessary
                    if app.file.backgroundcolor == 1
                        cut_out_logical = app.background_removal(temp_image{rotate_counter}, 0, app.Threshold_Slider.Value);
                    end
                    image_logical{rotate_counter} = cut_out_logical;
                    for i = 1:app.Iterations_EditField.Value
                        image_logical{rotate_counter} = Neighborhood_refinement(app, image_logical{rotate_counter});
                    end
                    temp_image{rotate_counter} = image_logical{rotate_counter} .* temp_image{rotate_counter};
                    % use lower part to find heel
                    [rows, ~, ~] = size(image_logical{rotate_counter});
                    starting_idx(rotate_counter) = floor((n_parts - 1) * rows/n_parts);
                    % partial binary image
                    binaryImage = logical(image_logical{rotate_counter}(starting_idx(rotate_counter):end,:));
                    binaryImage = imfill(binaryImage, 'holes');
                    binaryImage = bwareafilt(binaryImage, 1);
                    % complete binary image
                    binaryImage_com = logical(image_logical{rotate_counter}(:,:));
                    binaryImage_com = imfill(binaryImage_com, 'holes');
                    binaryImage_com = bwareafilt(binaryImage_com, 1);
                    % Get horizontal and vertical borederlines of foot
                    [x{rotate_counter},y{rotate_counter},x_left,~, x_right, ~] = horizontal_borderlines(app,binaryImage);
                    [~,~,~,~, ~, y_bot{rotate_counter}] = vertical_borderlines(app, binaryImage_com);
                    % calculate sum of(mean -value) for border pixels -->
                    % used to determine orientation with highest symmetry of
                    % heel
                    for i = 1:length(x_left)
                        diff_left(i) = (floor(x_left(i) - mean(x_left))).^2;
                    end
                    for j = 1:length(x_right)
                        diff_right(j) = (floor(x_right(j) - mean(x_right))).^2;
                    end
                    left_side_diff = sum(diff_left,2);
                    right_side_diff = sum(diff_right,2);
                    sum_values(rotate_counter) = left_side_diff - right_side_diff;
                    p = polyfit(x{rotate_counter},y{rotate_counter},2);
                    scaling_factor = 5;
                    new_x{rotate_counter} = x{rotate_counter}(1):(1/scaling_factor):x{rotate_counter}(end);
                    fitted_values{rotate_counter} = polyval(p,new_x{rotate_counter});
                    %% cancel further rotation if last two rotations made the
                    % result worse
                    if rotate_counter > 2
                        if abs(sum_values(rotate_counter)) > abs(sum_values(rotate_counter -1)) && abs(sum_values(rotate_counter -1)) > abs(sum_values(rotate_counter -2))
                            break
                        end
                    end
                end
                % find most symmetric heel rotation and determine heel point by
                % best fitting a parabola to rotated image
                [~,idx]=min(abs(sum_values));
                optimal_rotation = rotation_values(idx);
                [~,min_idx] = min(cell2mat(fitted_values(idx)));
                app.heel_point_idx = floor(new_x{idx}(min_idx));
                chosen_image = temp_image{idx};
                chosen_logical = image_logical{idx};
                heel_point_y = find(chosen_image(:,app.heel_point_idx) ~= 0,1,'last'); % find last row in column of parabola minimum
                cluster_rows = [-1,0,1,-1,0,1,-1,0,1]; % define 3x3 cluster with FoI in the middle
                cluster_cols = [-1,-1,-1,0,0,0,1,1,1];
                %% determine second toe
                borderline = nan(size(chosen_logical,2),1);
                for i = 1:size(chosen_logical,2)
                    for j = 1:size(chosen_logical,1)
                        if chosen_logical(j,i) == 1
                            borderline(i) = (size(chosen_logical,1) - j);
                            break;
                        end
                    end
                end
                %             plot(app.UIAxes2,borderline)
                [pks,locs] = findpeaks(borderline);
                if app.LeftfootButton.Value
                    second_toe = [size(chosen_logical,1) - pks(end - 1),locs(end-1)]; % second_toe(1) = row, (2) = col
                else
                    second_toe = [size(chosen_logical,1) - pks(2),locs(2)];
                end
                %             hold(app.UIAxes2,"on");
                %             for i = 1:length(pks)
                %                 line(app.UIAxes2,[locs(i) locs(i)],[0 1000],'Color','g' );
                %             end
                % mark heel and toe cluster red in the image
                for i = 1:length(cluster_cols)
                    chosen_image(heel_point_y + cluster_rows(i) ,app.heel_point_idx + cluster_cols(i),1 ) = 1;
                    chosen_image(heel_point_y + cluster_rows(i) ,app.heel_point_idx + cluster_cols(i),[2,3]) = 0;
                    chosen_image(second_toe(1) + cluster_rows(i) ,second_toe(2) + cluster_cols(i),1 ) = 1;
                    chosen_image(second_toe(1) + cluster_rows(i) ,second_toe(2) + cluster_cols(i),[2,3]) = 0;
                end
                %             version_control(app,chosen_image,'rotated');
                %             show_image(app);

                % rotate image back to original orientation
                back_rotated = imrotate(chosen_image, - optimal_rotation);
                back_rotated_logical = imrotate(chosen_logical, - optimal_rotation);
                [back_rotated,rows_delete,col_delete]  = remove_borders(app,back_rotated,0);
                back_rotated_logical(rows_delete,:) = [];
                back_rotated_logical(:, col_delete) = [];
                version_control(app,back_rotated,'back rotated');
                %             show_image(app);
                % find position of red heel cluster in original orientation
                % image for heel and toe
                [heel, toe] = find_marked_pixel(app,back_rotated, back_rotated_logical);
                %             version_control(app,back_rotated,'toe detection');
                %             show_image(app);
                %             hold(app.mainAxes,'on');
                %             toe_heel_line = line(app.mainAxes,[toe(2), heel(2)], [toe(1), heel(1)]);
                %             toe_heel_line.Color = 'r';
                % calculate angle from toe/heel line
                opp_side = heel(1) - toe(1);
                if toe(2) > heel(2)
                    adj_side = toe(2) - heel(2);
                    app.EditField.Value = 90 -atand(opp_side/ adj_side);
                else
                    adj_side = -(toe(2) - heel(2));
                    app.EditField.Value = -(90 -atand(opp_side/ adj_side));
                end
%                 final_image_rotation = imrotate(back_rotated,app.EditField.Value);
%                 final_image_logical = imrotate(back_rotated_logical, app.EditField.Value);
%                 [ final_image_rotation,rows_delete,col_delete]  = remove_borders(app, final_image_rotation,0);
%                 final_image_logical(rows_delete,:) = [];
%                 final_image_logical(:, col_delete) = [];
%                 [heel_final, toe_final] = find_marked_pixel(app,final_image_rotation,final_image_logical);
                %             version_control(app,final_image_rotation,'Toe heel vertical');
                %             %             show_image(app);
                %             hold(app.mainAxes,'on');
                %             toe_heel_line = line(app.mainAxes,[toe(2), heel(2)], [toe(1), heel(1)]);
                app.file.raw = imrotate(app.file.raw,app.EditField.Value); 
                [app.file.raw,~,~]  = remove_borders(app,app.file.raw,0);
                app.file.double = imrotate(app.file.double,app.EditField.Value);  
                [app.file.double,~,~]  = remove_borders(app,app.file.double,0);
                version_control(app,app.file.double,'original rotated');
                show_image(app);
%                 hold(app.mainAxes,'on');
%                 toe_heel_line = line(app.mainAxes,[toe_final(2), heel_final(2)], [toe_final(1), heel_final(1)]);
%                 toe_heel_line.Color = 'r';
                hourglass_cursor(app,0);
            end
            drawnow;
            figure(app.UIFigure)
        end

        % Selection changed function: RGBDimensionsButtonGroup
        function RGBDimensionsButtonGroupSelectionChanged(app, event)
            show_image(app);
        end

        % Button pushed function: Detect_foot_pixel_Button
        function Detect_foot_pixel_ButtonPushed(app, event)
            hourglass_cursor(app,1);
            app.file.cut_out_logical = background_removal(app,app.file.double, app.file.backgroundcolor, app.Threshold_Slider.Value);
            app.file.cut_out = app.file.cut_out_logical .* app.file.double;
            version_control(app, app.file.cut_out,'Background removed');
            show_image(app);
            hourglass_cursor(app,0);
        end

        % Button pushed function: Refine_detection_Button
        function Refine_detection_ButtonPushed(app, event)
            hourglass_cursor(app,1);
            image_logical = app.file.cut_out_logical;
            for i = 1:app.Iterations_EditField.Value
                image_logical = Neighborhood_refinement(app, image_logical);
            end
            app.file.logical = image_logical;
            app.file.logical_uint8 = cast(image_logical, 'uint8');
            if app.colorsChanged
                app.file.image_DW = app.file.coloration .* app.file.logical_uint8;
            else
                app.file.image_DW = app.file.raw .* app.file.logical_uint8;
            end

            version_control(app, app.file.image_DW, 'Neighbour refinement');
            show_image(app);

            hourglass_cursor(app, 0);
            % --> subplot
        end

        % Value changed function: ExpertmodeButton
        function ExpertmodeButtonValueChanged(app, event)
            if app.ExpertmodeButton.Value
                app.Threshold_Slider.Visible = "on";
                app.ThresholdSliderLabel.Visible = "on";
                app.Detection_window_EditField.Visible = "on";
                app.DetectionWindowEditFieldLabel.Visible = "on";
                app.Neighbour_threshold_Slider.Visible = "on";
                app.NeighbourthresholdLabel.Visible = "on";
                app.Iterations_EditField.Visible = 'on';
                app.alliterationsLabel.Visible = "on";
            else
                app.Threshold_Slider.Visible = "off";
                app.ThresholdSliderLabel.Visible = "off";
                app.Detection_window_EditField.Visible = "off";
                app.DetectionWindowEditFieldLabel.Visible = "off";
                app.Neighbour_threshold_Slider.Visible = "off";
                app.NeighbourthresholdLabel.Visible = "off";
                app.Iterations_EditField.Visible = 'off';
                app.alliterationsLabel.Visible = "off";
            end
        end

        % Button pushed function: nextimageButton
        function nextimageButtonPushed(app, event)
            hourglass_cursor(app,1)
            show_image(app,1)
            hourglass_cursor(app,0);
        end

        % Button pushed function: previousimageButton
        function previousimageButtonPushed(app, event)
            hourglass_cursor(app,1)
            show_image(app,-1)
            hourglass_cursor(app,0)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            hourglass_cursor(app,1)
            colorGraph(app)
            [app.file.foot_trained, app.file.logical_trained, colorMask] = classify_image(app, app.file.image_DW);
            for r = 1 : size(colorMask, 1)
                for c = 1 : size(colorMask, 2)
                    if colorMask(r, c)
                        app.file.foot_trained(r, c, 1) = 173;
                        app.file.foot_trained(r, c, 2) = 216;
                        app.file.foot_trained(r, c, 3) = 230;
                    end
                end
            end
            % determine default value for blob detection based on foot
            % contact area pixel count
            middle_index = floor(size(app.file.logical_trained,1) /2);
            total_pixel = size(app.file.logical_trained,1) * size(app.file.logical_trained,2);
            upper_image_count = sum(app.file.logical_trained(1:middle_index,:)  == 0,"all");
            lower_image_count = sum(app.file.logical_trained(middle_index:end,:) == 0,"all");
            percentage_upper = round(upper_image_count / total_pixel,2);
            percentage_lower = round(lower_image_count / total_pixel,2);
            if percentage_lower > percentage_upper
                app.Blobsize1nimagesizeEditField.Value = percentage_upper - (percentage_upper /3);
            else
                app.Blobsize1nimagesizeEditField.Value = percentage_lower - (percentage_lower /3);
            end
            version_control(app, app.file.foot_trained, 'Trained foot');
            show_image(app);
            hourglass_cursor(app,0)
        end

        % Button pushed function: findheelButton
        function findheelButtonPushed(app, event)
            [rows, ~, ~] = size(app.file.image_DW);
            % Get the mask where the region is solid.
            binaryImage = logical(app.file.logical((rows/2):end,:));
            % Fill it and take the largest blob:
            % binaryImage = imfill(binaryImage, 'holes');
            binaryImage = bwareafilt(binaryImage, 1);
            % Display the image.
            imshow(binaryImage,'Parent',app.mainAxes);

            regionWidth = zeros(rows, 1);
            for row = 1 : (rows/2)
                thisWidth = find(binaryImage(row, :), 1, 'last') - find(binaryImage(row, :), 1, 'first');
                if ~isempty(thisWidth)
                    regionWidth(row) = thisWidth;
                end
            end
            % Plot widths.
            figure(1)
            fontSize = 12;
            plot(regionWidth, 'b-', 'LineWidth', 2);
            grid on;
            title('Region Width vs. line down the image', 'FontSize', fontSize);
            xlabel('Row', 'FontSize', fontSize);
            ylabel('Width in pixels', 'FontSize', fontSize);

            % Measure elliptical properties
            props = regionprops(binaryImage, 'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'Centroid', 'Perimeter');
            %------------------------------------------------------------------
            % Create an ellipse with specified
            % semi-major and semi-minor axes, center, and image size.
            % From the FAQ: https://matlab.wikia.com/wiki/FAQ#How_do_I_create_an_ellipse.3F
            xCenter = props.Centroid(1);
            yCenter = props.Centroid(2);
            xRadius = props.MinorAxisLength / 2;
            yRadius = props.MajorAxisLength / 2;
            % Make an angle array of about the same number of angles as there are pixels in the perimeter.
            theta = linspace(0, 2*pi, ceil(props.Perimeter));
            x = xRadius * cos(theta) + xCenter;
            y = yRadius * sin(theta) + yCenter;
            % Now we might need to rotate the coordinates slightly.  Make a rotation matrix
            % Reference: https://en.wikipedia.org/wiki/Rotation_matrix
            angleInDegrees = props.Orientation - 90;
            rotationMatrix = [cosd(angleInDegrees), -sind(angleInDegrees); sind(angleInDegrees), cosd(angleInDegrees)];
            % Now do the rotation
            xy = [x', y'];
            xyRotated = xy * rotationMatrix;
            x = xyRotated(:, 1);
            y = xyRotated(:, 2);
            figure(2)
            imshow(app.file.image_DW((rows/2):end,:), []);
            title('Image with Fitted Ellipse', 'FontSize', fontSize, 'Interpreter', 'None');
            axis('on', 'image');
            hold on;
            plot(-x , -y , 'r-', 'LineWidth', 2);
            plot(xCenter, yCenter, 'r+', 'LineWidth', 2, 'MarkerSize', 13); % Put a cross at teh ellipse center.
            axis('image', 'on');
            grid on;
            % Print results
            fprintf('X Center = column #%.1f\n', xCenter);
            fprintf('Y Center = row #%.1f\n', yCenter);
            fprintf('Minor Axis Length = %.1f\n', xRadius);
            fprintf('Major Axis Length = %.1f\n', yRadius);
            fprintf('Orientation = %.1f degrees\n', angleInDegrees);


        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            % select only bottom part of image = 1/n_parts
            n_parts = 4;
            hourglass_cursor(app,1);
            if app.RightfootButton.Value
                rotation_values = 38:-4:-10;
            else
                rotation_values = -38:4:10;
            end
            sum_values = nan(1,length(rotation_values));
            for rotate_counter = 1:length(rotation_values)
                temp_image{rotate_counter} = imrotate(app.file.double,rotation_values(rotate_counter));
                [temp_image{rotate_counter},~,~] = remove_borders(app,temp_image{rotate_counter},app.file.backgroundcolor);
                cut_out_logical = app.background_removal(temp_image{rotate_counter}, app.file.backgroundcolor, app.Threshold_Slider.Value);
                temp_image{rotate_counter} = cut_out_logical .* temp_image{rotate_counter};
                % if image has white background, imrotate creates black
                % background for new fields --> both background removals
                % necessary
                if app.file.backgroundcolor == 1
                    cut_out_logical = app.background_removal(temp_image{rotate_counter}, 0, app.Threshold_Slider.Value);
                end
                image_logical{rotate_counter} = cut_out_logical;
                for i = 1:app.Iterations_EditField.Value
                    image_logical{rotate_counter} = Neighborhood_refinement(app, image_logical{rotate_counter});
                end
                temp_image{rotate_counter} = image_logical{rotate_counter} .* temp_image{rotate_counter};
                % use lower part to find heel
                [rows, ~, ~] = size(image_logical{rotate_counter});
                starting_idx(rotate_counter) = floor((n_parts - 1) * rows/n_parts);
                % partial binary image
                binaryImage = logical(image_logical{rotate_counter}(starting_idx(rotate_counter):end,:));
                binaryImage = imfill(binaryImage, 'holes');
                binaryImage = bwareafilt(binaryImage, 1);
                % complete binary image
                binaryImage_com = logical(image_logical{rotate_counter}(:,:));
                binaryImage_com = imfill(binaryImage_com, 'holes');
                binaryImage_com = bwareafilt(binaryImage_com, 1);
                % Get horizontal and vertical borederlines of foot
                [x{rotate_counter},y{rotate_counter},x_left,~, x_right, ~] = horizontal_borderlines(app,binaryImage);
                [~,~,~,~, ~, y_bot{rotate_counter}] = vertical_borderlines(app, binaryImage_com);
                % calculate sum of(mean -value) for border pixels -->
                % used to determine orientation with highest symmetry of
                % heel
                for i = 1:length(x_left)
                    diff_left(i) = (floor(x_left(i) - mean(x_left))).^2;
                end
                for j = 1:length(x_right)
                    diff_right(j) = (floor(x_right(j) - mean(x_right))).^2;
                end
                left_side_diff = sum(diff_left,2);
                right_side_diff = sum(diff_right,2);
                sum_values(rotate_counter) = left_side_diff - right_side_diff;
                p = polyfit(x{rotate_counter},y{rotate_counter},2);
                scaling_factor = 5;
                new_x{rotate_counter} = x{rotate_counter}(1):(1/scaling_factor):x{rotate_counter}(end);
                fitted_values{rotate_counter} = polyval(p,new_x{rotate_counter});

                %                 % Plot figures and values.
                %                 figure(1)
                %                 subplot(3, ceil(length(rotation_values)/3),rotate_counter)
                %                 plot(x{rotate_counter},y{rotate_counter}, 'o');
                %                 hold on;
                %                 grid on;
                %                 plot(x_left,y_left, 'r');
                %                 plot(x_right,y_right, 'g');
                %                 plot(new_x{rotate_counter},cell2mat(fitted_values(rotate_counter)),'r');
                %                 title(['Sum diff:',num2str(left_side_diff - right_side_diff)]);
                %                 line([mean(x_left), mean(x_left)],[0, rows/n_parts],'Color','b' );
                %                 line([mean(x_right), mean(x_right)],[0, rows/n_parts],'Color','b' );

                %% cancel further rotation if last two rotations made the
                % result worse
                if rotate_counter > 2
                    if abs(sum_values(rotate_counter)) > abs(sum_values(rotate_counter -1)) && abs(sum_values(rotate_counter -1)) > abs(sum_values(rotate_counter -2))
                        break
                    end
                end
            end

            % find most symmetric heel rotation and determine heel point by
            % best fitting a parabola to rotated image
            [~,idx]=min(abs(sum_values));
            optimal_rotation = rotation_values(idx);
            [~,min_idx] = min(cell2mat(fitted_values(idx)));
            app.heel_point_idx = floor(new_x{idx}(min_idx));
            chosen_image = temp_image{idx};
            chosen_logical = image_logical{idx};
            heel_point_y = find(chosen_image(:,app.heel_point_idx) ~= 0,1,'last'); % find last row in column of parabola minimum
            cluster_rows = [-1,0,1,-1,0,1,-1,0,1]; % define 3x3 cluster with FoI in the middle
            cluster_cols = [-1,-1,-1,0,0,0,1,1,1];
            %% determine second toe
            borderline = nan(size(chosen_logical,2),1);
            for i = 1:size(chosen_logical,2)
                for j = 1:size(chosen_logical,1)
                    if chosen_logical(j,i) == 1
                        borderline(i) = (size(chosen_logical,1) - j);
                        break;
                    end
                end
            end
            plot(app.UIAxes2,borderline)
            [pks,locs] = findpeaks(borderline);
            if app.LeftfootButton.Value
                second_toe = [size(chosen_logical,1) - pks(end - 1),locs(end-1)]; % second_toe(1) = row, (2) = col
            else
                second_toe = [size(chosen_logical,1) - pks(2),locs(2)];
            end
            hold(app.UIAxes2,"on");
            for i = 1:length(pks)
                line(app.UIAxes2,[locs(i) locs(i)],[0 1000],'Color','g' );
            end

            % mark heel and toe cluster red in the image
            for i = 1:length(cluster_cols)
                chosen_image(heel_point_y + cluster_rows(i) ,app.heel_point_idx + cluster_cols(i),1 ) = 1;
                chosen_image(heel_point_y + cluster_rows(i) ,app.heel_point_idx + cluster_cols(i),[2,3]) = 0;
                chosen_image(second_toe(1) + cluster_rows(i) ,second_toe(2) + cluster_cols(i),1 ) = 1;
                chosen_image(second_toe(1) + cluster_rows(i) ,second_toe(2) + cluster_cols(i),[2,3]) = 0;
            end
            version_control(app,chosen_image,'rotated');
            %             show_image(app);

            % rotate image back to original orientation
            back_rotated = imrotate(chosen_image, - optimal_rotation);
            back_rotated_logical = imrotate(chosen_logical, - optimal_rotation);
            [back_rotated,rows_delete,col_delete]  = remove_borders(app,back_rotated,0);
            back_rotated_logical(rows_delete,:) = [];
            back_rotated_logical(:, col_delete) = [];
            version_control(app,back_rotated,'back rotated');
            %             show_image(app);
            % find position of red heel cluster in original orientation
            % image for heel and toe
            [heel, toe] = find_marked_pixel(app,back_rotated, back_rotated_logical);
            version_control(app,back_rotated,'toe detection');
            %             show_image(app);
            hold(app.mainAxes,'on');
            toe_heel_line = line(app.mainAxes,[toe(2), heel(2)], [toe(1), heel(1)]);
            toe_heel_line.Color = 'r';
            % calculate angle from toe/heel line
            opp_side = heel(1) - toe(1);
            if toe(2) > heel(2)
                adj_side = toe(2) - heel(2);
                app.EditField.Value = 90 -atand(opp_side/ adj_side);
            else
                adj_side = -(toe(2) - heel(2));
                app.EditField.Value = -(90 -atand(opp_side/ adj_side));
            end
            final_image_rotation = imrotate(back_rotated,app.EditField.Value);
            final_image_logical = imrotate(back_rotated_logical, app.EditField.Value);
            [ final_image_rotation,rows_delete,col_delete]  = remove_borders(app, final_image_rotation,0);
            final_image_logical(rows_delete,:) = [];
            final_image_logical(:, col_delete) = [];
            [heel_final, toe_final] = find_marked_pixel(app,final_image_rotation,final_image_logical);
            version_control(app,final_image_rotation,'Toe heel vertical');
            %             show_image(app);
            hold(app.mainAxes,'on');
            toe_heel_line = line(app.mainAxes,[toe(2), heel(2)], [toe(1), heel(1)]);

            
            original_rotated = imrotate(app.file.image_DW,app.EditField.Value);
            [original_rotated,rows_delete,col_delete]  = remove_borders(app,original_rotated,0);
            original_logical_rotated = imrotate(app.file.logical_uint8, app.EditField.Value);
            original_logical_rotated(rows_delete,:) = [];
            original_logical_rotated(:, col_delete) = [];
            version_control(app,original_rotated,'original rotated');
            show_image(app);
            hold(app.mainAxes,'on');
            toe_heel_line = line(app.mainAxes,[toe_final(2), heel_final(2)], [toe_final(1), heel_final(1)]);
            toe_heel_line.Color = 'r';
            hourglass_cursor(app,0);
        end

        % Button pushed function: determinepointsButton
        function determinepointsButtonPushed(app, event)
            foot_contact_area = logical(app.file.logical_trained);
            % check if contact area is made of one large or multiple blobs
            for i = 1:size(foot_contact_area,1)
                if sum(double(foot_contact_area(i,:))) == size(foot_contact_area,2)
                    row_foot(i) = 0;
                else
                    row_foot(i) = 1;
                end
            end
            diff_sum_row = diff(row_foot);
            diff_position_pos = find(diff_sum_row == 1);
            diff_position_neg = find(diff_sum_row == -1);
            diff_pos = sort([diff_position_pos,diff_position_neg],'ascend');
            forefoot_end_idx = [];
            hindfoot_start_idx = [];
            if length(diff_pos) > 2 && diff_pos(2) > (size(foot_contact_area,1)/4)
                continuous_contact = 0;
                forefoot_end_idx = find(diff_pos > (size(foot_contact_area,1)/4),1,"first");
                forefoot_end = diff_pos(forefoot_end_idx);
                hindfoot_start_idx = find(diff_pos < ((size(foot_contact_area,1) * 0.75)),1,"last");
                hindfoot_start = diff_pos(hindfoot_start_idx) + 1;
            else
                continuous_contact = 1;
            end
            figure(10)
            plot(diff_sum_row);
            % connect foot areas between lowest and highest point


            % weil logical verdreht , background ist 1 und nicht 0 blob
            % also background anstatt fuß sonst - bei contact detection
            % dann ändern
            sF = size(foot_contact_area);
            for i = 1:sF(1)
                for j = 1:sF(2)
                    if foot_contact_area(i,j)
                        foot_contact_area(i,j) = 0;
                    else
                        foot_contact_area(i,j) = 1;
                    end
                end
            end
            %%
            % blob search
            min_blob_size = (sF(1) * sF(2)) .* app.Blobsize1nimagesizeEditField.Value;
            bwfilt = bwareafilt(foot_contact_area,[min_blob_size (sF(1) * sF(2))]); % Absurde range- aber funkt.- muss nochmal nachschauen was genau die range ist
            % TW_28_12_2012_li / 10_240_L großer zeh noch mit geplottet wegen range ->
            % anpassen
            imshow(bwfilt, "Parent", app.mainAxes)
            hold(app.mainAxes, 'on')
            % plot lines if multiple blobs
            if  continuous_contact == 0
                FF_end_line = line(app.mainAxes,[1 size(bwfilt,1)],[forefoot_end forefoot_end]);
                FF_end_line.Color = 'r';
                HF_start_line = line(app.mainAxes,[1 size(bwfilt,1)],[hindfoot_start hindfoot_start]);
                HF_start_line.Color = 'r';
            end
            % boundaries 
            boundaries_CO = bwboundaries(bwfilt);
            numberOfBoundaries = size(boundaries_CO,1);
            for i = 1: numberOfBoundaries
                thisBoundary = boundaries_CO{i};
                x= thisBoundary(:,2);
                y= thisBoundary(:,1);
                plot(app.mainAxes, x,y,'r-')
            end
%             version_control(app,foot_contact_area,'logical');
            save('foot_contact_area','foot_contact_area')
            save('bwfilt',"bwfilt")
            %             show_image(app);

            %% Arch length index
            med_pic_1 = round(size(bwfilt, 1)./2);
            row = nan(1, size(bwfilt, 1));
            col = nan(1, size(bwfilt, 1));
            % find border pixels i.e. first and last depending on foot
            for i = 1:size(bwfilt, 1)
                if app.RightfootButton.Value
                    k =  find(bwfilt(i,:),1,'first');
                else
                    k =  find(bwfilt(i,:),1,'last');
                end
                if ~isempty(k)
                    col(i) = k ;
                    row(i) = i;
                end
            end
            % find most medial point
            if app.RightfootButton.Value
                [col_met, row_met ] = min(col(1:med_pic_1));
                [col_heel, row_heel] = min(col(med_pic_1:end));
            else
                [col_met, row_met ] = max(col(1:med_pic_1));
                [col_heel, row_heel] = max(col(med_pic_1:end));
            end
            % correct for midline separation of search
            row_heel = row_heel + med_pic_1 - 1;
            % plot both points
            plot(app.mainAxes, col_met,row_met,'go','MarkerSize',10)
            plot(app.mainAxes,col_heel,row_heel,'go','MarkerSize',10)
            % calculate diff in rows and col between the two medial points
            diff_col = col_heel - col_met;
            diff_row = row_heel - row_met;
            % use pythagorean theorem to determine length of connecting
            % line
            connecting_line_length = sqrt((diff_col.^2 + diff_row.^2));
            % plot line
            p = line(app.mainAxes,[col_met, col_heel], [row_met, row_heel]);
            p.Color = 'r';
            % trace boundaries in starting at medial point on forefoot,
            % direction and rotation preference changed depending on foot
            % side
            if app.RightfootButton.Value
                boundaries = bwtraceboundary(bwfilt,[row_met col_met],'NW',8,inf,'counterclockwise');
            else
                boundaries = bwtraceboundary(bwfilt,[row_met col_met],'NE',8,inf,'clockwise');
            end
            % seperate inner border from whole border
            x_border = boundaries(:,2);
            y_border= boundaries(:,1);
            x_filtered = [];
            y_filtered = [];
            for i = 1:length(x_border)
                if x_border(i) == col_heel  && y_border(i) == row_heel
                    x_filtered = [x_filtered,x_border(i)];
                    y_filtered = [y_filtered,y_border(i)];
                    break
                else
                    x_filtered = [x_filtered,x_border(i)];
                    y_filtered = [y_filtered,y_border(i)];
                end
            end
            % plot resulting borderline in green
            plot(app.mainAxes,x_filtered,y_filtered,'g-')
            app.ArchlengthindexEditField.Value = connecting_line_length ./ length(x_filtered);

            %% Arch Clarke Angle
            % trace boundaries starting at medial point on forefoot
            if app.RightfootButton.Value % right foot
                limits = bwtraceboundary(bwfilt, [row_met col_met], 'NW', 8, inf, 'counterclockwise');
            else % left foot
                limits = bwtraceboundary(bwfilt, [row_met col_met], 'NE', 8, inf, 'clockwise');
            end
            assignin("base", "limits", limits)
            assignin("base", "bwfilt", bwfilt)
            %% to do
            % - correct for foots where a missing no connection is found in
            %   midfoot area --> two areas (heel and forefoot)

            % Footprint index
            % determine pixels that the line, that connects both medial points, lies on
            coeffs = polyfit([row_met,row_heel],[col_met, col_heel],1);
            % create vector with all rows between two medial points
            line_rows = row_met:1:row_heel;
            line_cols = round(polyval(coeffs,line_rows));
            % make copy of foot image
            app.file.footprint_index_image = app.file.foot_trained;
            assignin("base",'image2',app.file.footprint_index_image);
            % find max/min border pixel for each row, depending on side;
            % count number of zero pixels in each row between line_col and
            % border_cols
            border_cols = nan(numel(row_met:row_heel),1);
            idx_ctr = 0;
            for row_ctr = row_met:row_heel
                idx_ctr = idx_ctr +1;
                idces_current_row = (y_filtered == row_ctr);
                if app.RightfootButton.Value
                    if ~isempty(max(x_filtered(idces_current_row)))
                        border_cols(idx_ctr) = max(x_filtered(idces_current_row));
                        zero_count(idx_ctr) = sum(bwfilt(row_ctr,line_cols(idx_ctr):border_cols(idx_ctr)) == 0);
                        app.file.footprint_index_image(row_ctr,bwfilt(row_ctr,line_cols(idx_ctr):border_cols(idx_ctr)) == 0,2) = 255;
                        app.file.footprint_index_image(row_ctr,bwfilt(row_ctr,line_cols(idx_ctr):border_cols(idx_ctr)) == 0,[1,3]) = 0; 
                    end
                else
                    if ~isempty(max(x_filtered(idces_current_row)))
                        border_cols(idx_ctr) = min(x_filtered(idces_current_row));
                        zero_count(idx_ctr) = sum(bwfilt(row_ctr,border_cols(idx_ctr):line_cols(idx_ctr)) == 0);
                        app.file.footprint_index_image(row_ctr,bwfilt(row_ctr,border_cols(idx_ctr):line_cols(idx_ctr)) == 0,2) = 255;
                        app.file.footprint_index_image(row_ctr,bwfilt(row_ctr,border_cols(idx_ctr):line_cols(idx_ctr)) == 0,[1,3]) = 0;
                    end
                end
            end
            figure(1)
            plot(zero_count);
            area_line_border = sum(zero_count,"all");
            area_whole_foot = sum(bwfilt,"all");
            app.FootprintindexEditField.Value = area_line_border / area_whole_foot;
            % create image with colored areas
            for i = 1:size(app.file.footprint_index_image,1)
                for j = 1:size(app.file.footprint_index_image,2)
                    if bwfilt(i,j)
                       app.file.footprint_index_image(i,j,1) = 255;
                       app.file.footprint_index_image(i,j,2) = 0;
                       app.file.footprint_index_image(i,j,3) = 0;
                    end
                end
            end

            
        end

        % Selection changed function: RGBDimensionsButtonGroup_2
        function RGBDimensionsSelectionChanged(app, event)
            hourglass_cursor(app, 1)
            colorGraph(app)
            hourglass_cursor(app, 0)
        end

        % Value changed function: ArchlengthindexButton
        function ArchlengthindexButtonValueChanged(app, event)
        clear_state_buttons(app);
        app.ArchlengthindexButton.Value = 1;
        end

        % Value changed function: FootprintindexButton
        function FootprintindexButtonValueChanged(app, event)
        clear_state_buttons(app);
        app.FootprintindexButton.Value = 1;
        version_control(app,app.file.footprint_index_image,'Footprint index');
        show_image(app);
        end

        % Button pushed function: ShowColorGraphButton
        function ShowColorGraphButtonPushed(app, event)
            hourglass_cursor(app, 1)
            colorGraph(app)
            hourglass_cursor(app, 0)
        end

        % Button pushed function: CorrectColorsButton
        function CorrectColorsButtonPushed(app, event)
            app.colorsChanged = 1;
            hourglass_cursor(app, 1)
            app.file.coloration = app.file.raw;
            colorCorrection(app);
            app.file.double = cast(app.file.coloration, 'double') / 255;
            version_control(app, app.file.double, 'Colors corrected');
            show_image(app);
            hourglass_cursor(app, 0)
        end

        % Button pushed function: ResetColorsButton
        function ResetColorsButtonPushed(app, event)
            app.file.double = cast(app.file.raw, 'double') / 255;
            version_control(app, app.file.double, 'raw');
            show_image(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {466, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.mainAxes = uiaxes(app.LeftPanel);
            title(app.mainAxes, 'Title')
            xlabel(app.mainAxes, 'X')
            ylabel(app.mainAxes, 'Y')
            zlabel(app.mainAxes, 'Z')
            app.RGBDimensionsButtonGroup = uibuttongroup(app.LeftPanel);
            app.RGBDimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RGBDimensionsButtonGroupSelectionChanged, true);
            app.RGBDimensionsButtonGroup.Title = 'RGB Dimensions: ';
            app.AllButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.AllButton.Text = 'All';
            app.BlueButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.GreenButton.Text = 'Green ';
            app.RedButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.RedButton.Text = 'Red ';
            app.RedButton.Value = true;
            app.nextimageButton = uibutton(app.LeftPanel, 'push');
            app.nextimageButton.ButtonPushedFcn = createCallbackFcn(app, @nextimageButtonPushed, true);
            app.nextimageButton.Text = 'next image >>';
            app.previousimageButton = uibutton(app.LeftPanel, 'push');
            app.previousimageButton.ButtonPushedFcn = createCallbackFcn(app, @previousimageButtonPushed, true);
            app.previousimageButton.Text = '<< previous image';
            app.fileName = uilabel(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.LoadimagePanel = uipanel(app.StartTab);
            app.LoadimagePanel.Title = 'Load image';
            app.LoadButton = uibutton(app.LoadimagePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UselastpathCheckBox = uicheckbox(app.LoadimagePanel);
            app.UselastpathCheckBox.Text = 'Use last path ';
            app.UselastpathCheckBox.Value = true;
            app.ExpertmodeButton = uibutton(app.LoadimagePanel, 'state');
            app.ExpertmodeButton.ValueChangedFcn = createCallbackFcn(app, @ExpertmodeButtonValueChanged, true);
            app.ExpertmodeButton.Text = 'Expert mode';
            app.ExpertmodeButton.Value = true;
            app.rotateimageButton = uibutton(app.LoadimagePanel, 'state');
            app.rotateimageButton.Text = 'rotate image';
            app.rotateimageButton.Value = true;
            app.QuickcheckimagePanel = uipanel(app.StartTab);
            app.QuickcheckimagePanel.Title = 'Quick check image';
            app.RotateButton = uibutton(app.QuickcheckimagePanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.EditFieldLabel = uilabel(app.QuickcheckimagePanel);
            app.EditFieldLabel.Text = '°';
            app.EditField = uieditfield(app.QuickcheckimagePanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.rotateDescription = uilabel(app.QuickcheckimagePanel);
            app.rotateDescription.Text = 'text about why this button exists';
            app.FoottypeButtonGroup = uibuttongroup(app.StartTab);
            app.FoottypeButtonGroup.Title = 'Foot type';
            app.LeftfootButton = uiradiobutton(app.FoottypeButtonGroup);
            app.LeftfootButton.Text = 'Left foot';
            app.LeftfootButton.Value = true;
            app.RightfootButton = uiradiobutton(app.FoottypeButtonGroup);
            app.RightfootButton.Text = 'Right foot';
            app.WhenloadingtheimagetheappLabel = uilabel(app.FoottypeButtonGroup);
            app.WhenloadingtheimagetheappLabel.Text = '';
            app.foottypeDescription = uilabel(app.FoottypeButtonGroup);
            app.foottypeDescription.Text = 'When loading the image, the app tries to calculate whether you uploaded the image of a right or a left foot. However, some images are unclear to detect. Please check this field and adapt if needed. This information is neccessary as further computations take it into account.';
            app.colorationCorrection = uipanel(app.StartTab);
            app.colorationCorrection.Title = 'Coloration correction';
            app.colorDescription = uilabel(app.colorationCorrection);
            app.colorDescription.Text = 'Some images might need a color correction to improve contact detection. First try foot analysis without any changes in coloration. If your results are unadequate and your photo seems unnaturally yellow-ish, try enhancing blue and red hues to correct.';
            app.EnhanceblueSliderLabel = uilabel(app.colorationCorrection);
            app.EnhanceblueSliderLabel.Text = 'Enhance blue';
            app.blueSlider = uislider(app.colorationCorrection);
            app.blueSlider.Limits = [1 2];
            app.blueSlider.MajorTicks = [1 1.2 1.4 1.6 1.8 2];
            app.blueSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2];
            app.blueSlider.Value = 1.6;
            app.EnhanceredSliderLabel = uilabel(app.colorationCorrection);
            app.EnhanceredSliderLabel.Text = 'Enhance red';
            app.redSlider = uislider(app.colorationCorrection);
            app.redSlider.Limits = [1 2];
            app.redSlider.MajorTicks = [1 1.2 1.4 1.6 1.8 2];
            app.redSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2];
            app.redSlider.Value = 1.2;
            app.CorrectColorsButton = uibutton(app.colorationCorrection, 'push');
            app.CorrectColorsButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectColorsButtonPushed, true);
            app.CorrectColorsButton.Text = 'Correct Colors';
            app.ResetColorsButton = uibutton(app.colorationCorrection, 'push');
            app.ResetColorsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetColorsButtonPushed, true);
            app.ResetColorsButton.Text = 'Reset Colors';
            app.DataProcessingTab = uitab(app.TabGroup);
            app.DataProcessingTab.Title = 'Data Processing ';
            app.BackgroundDetectionPanel = uipanel(app.DataProcessingTab);
            app.BackgroundDetectionPanel.Title = 'Background Detection';
            app.ThresholdSliderLabel = uilabel(app.BackgroundDetectionPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.Threshold_Slider = uislider(app.BackgroundDetectionPanel);
            app.Threshold_Slider.Value = 95;
            app.Detect_foot_pixel_Button = uibutton(app.BackgroundDetectionPanel, 'push');
            app.Detect_foot_pixel_Button.ButtonPushedFcn = createCallbackFcn(app, @Detect_foot_pixel_ButtonPushed, true);
            app.Detect_foot_pixel_Button.Text = 'Detect foot pixel';
            app.Refine_detection_Button = uibutton(app.BackgroundDetectionPanel, 'push');
            app.Refine_detection_Button.ButtonPushedFcn = createCallbackFcn(app, @Refine_detection_ButtonPushed, true);
            app.Refine_detection_Button.Text = 'Refine detection';
            app.NeighbourthresholdLabel = uilabel(app.BackgroundDetectionPanel);
            app.NeighbourthresholdLabel.Text = {'Neighbour '; 'threshold'};
            app.Neighbour_threshold_Slider = uislider(app.BackgroundDetectionPanel);
            app.Neighbour_threshold_Slider.Value = 75;
            app.alliterationsLabel = uilabel(app.BackgroundDetectionPanel);
            app.alliterationsLabel.Text = 'Iterations';
            app.Iterations_EditField = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.Iterations_EditField.Limits = [1 20];
            app.Iterations_EditField.Value = 4;
            app.DetectionWindowEditFieldLabel = uilabel(app.BackgroundDetectionPanel);
            app.DetectionWindowEditFieldLabel.Text = 'Detection Window';
            app.Detection_window_EditField = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.Detection_window_EditField.Limits = [3 7];
            app.Detection_window_EditField.Value = 3;
            app.ContactDetectionPanel = uipanel(app.DataProcessingTab);
            app.ContactDetectionPanel.Tooltip = {'Intervall between min and each max'};
            app.ContactDetectionPanel.Title = 'Contact Detection';
            app.UIAxes2 = uiaxes(app.ContactDetectionPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ProcessButton = uibutton(app.ContactDetectionPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = {'Process'; ''};
            app.CIBandwidthEditFieldLabel = uilabel(app.ContactDetectionPanel);
            app.CIBandwidthEditFieldLabel.Text = 'CI Band width';
            app.CIBandwidthEditField = uieditfield(app.ContactDetectionPanel, 'numeric');
            app.CIBandwidthEditField.Tooltip = {'Intervall between min and each max'};
            app.CIBandwidthEditField.Value = 0.4;
            app.RGBDimensionsButtonGroup_2 = uibuttongroup(app.ContactDetectionPanel);
            app.RGBDimensionsButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @RGBDimensionsSelectionChanged, true);
            app.RGBDimensionsButtonGroup_2.Title = 'RGB Dimensions: ';
            app.BlueButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.BlueButton_2.Text = 'Blue';
            app.GreenButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.GreenButton_2.Text = 'Green';
            app.RedButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.RedButton_2.Text = 'Red';
            app.RedButton_2.Value = true;
            app.ShowColorGraphButton = uibutton(app.ContactDetectionPanel, 'push');
            app.ShowColorGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ShowColorGraphButtonPushed, true);
            app.ShowColorGraphButton.Text = 'Show Color Graph';
            app.alliterationsLabel_2 = uilabel(app.ContactDetectionPanel);
            app.alliterationsLabel_2.Text = 'Refinement iterations';
            app.Iterations_EditField_2 = uieditfield(app.ContactDetectionPanel, 'numeric');
            app.Iterations_EditField_2.Limits = [1 10];
            app.Iterations_EditField_2.Value = 4;
            app.CutoffvalueSpinnerLabel = uilabel(app.ContactDetectionPanel);
            app.CutoffvalueSpinnerLabel.Text = 'Cut-off value';
            app.CutoffvalueSpinner = uispinner(app.ContactDetectionPanel);
            app.CutoffvalueSpinner.Step = 0.1;
            app.CutoffvalueSpinner.Limits = [0 1];
            app.CutoffvalueSpinner.Value = 0.7;
            app.LandmarkIdentificationTab = uitab(app.TabGroup);
            app.LandmarkIdentificationTab.Title = 'Landmark Identification';
            app.determinepointsButton = uibutton(app.LandmarkIdentificationTab, 'push');
            app.determinepointsButton.ButtonPushedFcn = createCallbackFcn(app, @determinepointsButtonPushed, true);
            app.determinepointsButton.Text = 'determine points';
            app.findheelButton = uibutton(app.LandmarkIdentificationTab, 'push');
            app.findheelButton.ButtonPushedFcn = createCallbackFcn(app, @findheelButtonPushed, true);
            app.findheelButton.Text = 'find heel';
            app.ArchlengthindexEditField = uieditfield(app.LandmarkIdentificationTab, 'numeric');
            app.FootprintindexEditField = uieditfield(app.LandmarkIdentificationTab, 'numeric');
            app.ArchlengthindexButton = uibutton(app.LandmarkIdentificationTab, 'state');
            app.ArchlengthindexButton.ValueChangedFcn = createCallbackFcn(app, @ArchlengthindexButtonValueChanged, true);
            app.ArchlengthindexButton.Text = 'Arch length index';
            app.FootprintindexButton = uibutton(app.LandmarkIdentificationTab, 'state');
            app.FootprintindexButton.ValueChangedFcn = createCallbackFcn(app, @FootprintindexButtonValueChanged, true);
            app.FootprintindexButton.Text = 'Footprint index';
            app.UITable = uitable(app.LandmarkIdentificationTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Blobsize1nimagesizeLabel = uilabel(app.LandmarkIdentificationTab);
            app.Blobsize1nimagesizeLabel.Text = 'Blob size (1/n * image size)';
            app.Blobsize1nimagesizeEditField = uieditfield(app.LandmarkIdentificationTab, 'numeric');
            app.Blobsize1nimagesizeEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_Feet_Classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end